classdef HAI_Pecan_Pruner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Toolbar                        matlab.ui.container.Toolbar
        seleccinoarArchivo             matlab.ui.container.toolbar.PushTool
        PushTool2                      matlab.ui.container.toolbar.PushTool
        salir                          matlab.ui.container.toolbar.PushTool
        AjustedevaloresidealesPanel    matlab.ui.container.Panel
        VolumendemaderapodadoLabel     matlab.ui.control.Label
        CheckMadera                    matlab.ui.control.CheckBox
        ValoraevitarLabel_6            matlab.ui.control.Label
        Image_50                       matlab.ui.control.Image
        AjusteVolumenmaderaEvi         matlab.ui.control.NumericEditField
        ValorindiferenteLabel_7        matlab.ui.control.Label
        ValoridealLabel_7              matlab.ui.control.Label
        Image_14                       matlab.ui.control.Image
        Image_13                       matlab.ui.control.Image
        AjusteVolumenmaderaInd         matlab.ui.control.NumericEditField
        AjusteVolumenmaderaAlto        matlab.ui.control.NumericEditField
        ValoraevitarLabel_2            matlab.ui.control.Label
        Image_46                       matlab.ui.control.Image
        AjusteDishelicoidalEvi         matlab.ui.control.NumericEditField
        ValorindiferenteLabel_2        matlab.ui.control.Label
        ValoridealLabel_2              matlab.ui.control.Label
        Image_8                        matlab.ui.control.Image
        Image_7                        matlab.ui.control.Image
        AjusteDishelicoidalInd         matlab.ui.control.NumericEditField
        AjusteDishelicoidalAlto        matlab.ui.control.NumericEditField
        CheckDisHeli                   matlab.ui.control.CheckBox
        DistribucinhelicoidalLabel     matlab.ui.control.Label
        CheckPrimarias                 matlab.ui.control.CheckBox
        ValoraevitarLabel              matlab.ui.control.Label
        Image_45                       matlab.ui.control.Image
        AjusteprimariasEvi             matlab.ui.control.NumericEditField
        ValorindiferenteLabel          matlab.ui.control.Label
        ValoridealLabel_3              matlab.ui.control.Label
        Image_2                        matlab.ui.control.Image
        AjusteprimariasInd             matlab.ui.control.NumericEditField
        Image                          matlab.ui.control.Image
        AjusteprimariasAlto            matlab.ui.control.NumericEditField
        RamasprimariasLabel            matlab.ui.control.Label
        CheckLuz                       matlab.ui.control.CheckBox
        CheckDisVert                   matlab.ui.control.CheckBox
        CheckApertura                  matlab.ui.control.CheckBox
        CheckRamasFinas                matlab.ui.control.CheckBox
        ValoraevitarLabel_3            matlab.ui.control.Label
        Image_47                       matlab.ui.control.Image
        AjusteLuzEvi                   matlab.ui.control.NumericEditField
        ValorindiferenteLabel_3        matlab.ui.control.Label
        ValoridealLabel                matlab.ui.control.Label
        Image_10                       matlab.ui.control.Image
        Image_9                        matlab.ui.control.Image
        AjusteLuzInd                   matlab.ui.control.NumericEditField
        AjusteLuzAlto                  matlab.ui.control.NumericEditField
        LAILabel                       matlab.ui.control.Label
        ValoraevitarLabel_7            matlab.ui.control.Label
        Image_51                       matlab.ui.control.Image
        AjusteDisverticalEvi           matlab.ui.control.NumericEditField
        ValorindiferenteLabel_6        matlab.ui.control.Label
        ValoridealLabel_6              matlab.ui.control.Label
        Image_6                        matlab.ui.control.Image
        Image_5                        matlab.ui.control.Image
        AjusteDisverticalInd           matlab.ui.control.NumericEditField
        AjusteDisverticalAlto          matlab.ui.control.NumericEditField
        DistribucinverticalLabel       matlab.ui.control.Label
        ValoraevitarLabel_5            matlab.ui.control.Label
        ValoraevitarLabel_4            matlab.ui.control.Label
        Image_49                       matlab.ui.control.Image
        Image_48                       matlab.ui.control.Image
        AjusteAperturaEvi              matlab.ui.control.NumericEditField
        AjusteRamasfinasEvi            matlab.ui.control.NumericEditField
        ValorindiferenteLabel_5        matlab.ui.control.Label
        ValorindiferenteLabel_4        matlab.ui.control.Label
        ValoridealLabel_5              matlab.ui.control.Label
        ValoridealLabel_4              matlab.ui.control.Label
        Image_12                       matlab.ui.control.Image
        Image_11                       matlab.ui.control.Image
        Image_4                        matlab.ui.control.Image
        Image_3                        matlab.ui.control.Image
        AjusteAperturaInd              matlab.ui.control.NumericEditField
        AjusteAperturaAlto             matlab.ui.control.NumericEditField
        AperturadecopaLabel            matlab.ui.control.Label
        AjusteRamasfinasInd            matlab.ui.control.NumericEditField
        AjusteRamasfinasAlto           matlab.ui.control.NumericEditField
        RamasfinasLabel                matlab.ui.control.Label
        Image8                         matlab.ui.control.Image
        InstanciainicialdelnogalPanel  matlab.ui.container.Panel
        FinalizarButton                matlab.ui.control.Button
        ContinuarButton                matlab.ui.control.Button
        IniciarpodaButton              matlab.ui.control.Button
        Image2_7                       matlab.ui.control.Image
        AxesNube                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        nombre_archivo = '' % nombre de la nube de puntos
    end
    
    properties (Access = public)
        iter = 0;
        rep = 0;
        phase = 0; % es la fase segun la interaccion con el DM
        iter_max = 180; %numero maximo de iteraciones en caso de converger
        rep_max = 5; %cantidad de iteraciones para cumplir el criterio de convergencia, la cantidad de veces que se mantiene best
        a1 = .75; %probabilidad de explotacion en la regla de seleccion
        a2 = .90; %parametro de la regla de seleccion, para el uso de la ruleta
        p = .10 %tasa de evaporacion
        W = .6; %nivel de conocimiento maximo que las hormigas pueden aportar
        objetivos = 7 % cantidad de objetivos
        w % vector de pesos w para cada hormiga
        n_hormigas = 100 % tamano de la poblacion
        P % nube de puntos LiDAR
        seleccionado = 0 %variable para seleccionar un nogal
        inputs %inputs para QSM
        QSM_hormigas_info % para almacenar las carcteristicas de las hormigas
        Nogal_Inicial % para almacenar la informacion del nogal inciial
        feromona % matriz de feromona de tamano n
        feromona_cortes %vector feromona del tamano de los cilindros
        Eliteants = struct('valor_objetivos', {}, 'valor_verdad', {}, 'predicado', {}, 'predicado_round', {},'ant', {}, 'puntos_corte', {}, 'cortes', {}, 'cylinder',{}, 'treeData', {}, 'branchData', {}) %conjunto global no dominado
        best % mejor solucion conocida
        hormigas %array de vectores binario que representan una hormiga cada vector
        cortes %puntos de corte en las hormigas
        matriz_dependencias %matriz que contiene los cilindros que dependen de podar un cilindro i
        NSlocal = struct('valor_objetivos', {}, 'valor_verdad', {}, 'predicado',{},'predicado_round', {},'ant', {}, 'puntos_corte', {}, 'cortes', {}, 'cylinder',{}, 'treeData', {}, 'branchData', {}) %estructura de las soluciones
        O %conjunto de soluciones factibles
        Oglobal %conjunto de soluciones factibles global
        cant_cilindros %cantidad de cilindros en la estructura
        metas = struct('apertura', {}, 'disVertical', {}, 'LAI', {}, 'primarias', {}, 'helicoidal', {}, 'madera', {}, 'finas', {}) %estructura para actualizar las metas del usuario
        parametros = struct('alfa', {}, 'alfa_far', {}, 'm', {}, 'M', {}, 'lambda', {}, 'nadir', {}, 'ideal', {}, 'prioridad', {}, 'max_prioridad', {});
        chechboxes % para indicar la prioridad en los atributos.
        nombresObjetivos % nombres de los objetivos
        nombresCamposIdeal % nombre de los campos ideal
    end
    
    methods (Access = public)
        
        function evaporar_feromona(app)
            app.feromona = (app.feromona - app.p) .* app.feromona; % evaporacion de feromona en cada elemento
            app.feromona_cortes = (app.feromona_cortes - app.p) .* app.feromona_cortes; % evaporacion de feromona_cortes en cada elemento.
        end


        function intensificar_feromona(app, NSlocal)
            sol_NSlocal = numel(NSlocal);
            for ant = 1:sol_NSlocal
                cant_cortes = NSlocal(ant).cortes;
                delta_cortes = ((sol_NSlocal - ant + 1) / sol_NSlocal) * (1 - app.feromona_cortes(cant_cortes));
                app.feromona_cortes(cant_cortes) = app.feromona_cortes(cant_cortes) + delta_cortes;
                vector_hormiga = NSlocal(ant).puntos_corte;
                for c=1:length(vector_hormiga)
                    delta = ((sol_NSlocal - ant + 1) / sol_NSlocal) .* (1 - app.feromona(vector_hormiga(c), vector_hormiga));
                    app.feromona(vector_hormiga(c), vector_hormiga) = app.feromona(vector_hormiga(c), vector_hormiga) + delta;
                end
            end
        end


        function graficar(app)

            if ~isempty(app.Eliteants)

                for i = 1: length(app.Eliteants)
                    valores_verdad = app.Eliteants(i).valor_objetivos;
                    nuevaVentanaModal = modalPoda();
                    nuevaVentanaModal.setPrincipalApp(app, app.Eliteants(i).cylinder);
                    nuevaVentanaModal.num_qsm = i;
                    titulo = sprintf('Propuesta de poda %d', i);
                    nuevaVentanaModal.Propuestadepoda1ButtonGroup.Title = titulo;
                    nuevaVentanaModal.Primarias.Value = double(valores_verdad(1));
                    nuevaVentanaModal.Disvertical.Value = double(valores_verdad(2));
                    nuevaVentanaModal.Dishelicoidal.Value = double(valores_verdad(3));
                    nuevaVentanaModal.Luz.Value = double(valores_verdad(4));
                    nuevaVentanaModal.Finas.Value = double(valores_verdad(5));
                    nuevaVentanaModal.Angulos.Value = double(valores_verdad(6));
                    nuevaVentanaModal.Volpodado.Value = double(valores_verdad(7));
                end

            else
              
                    valores_verdad = app.best(1).valor_objetivos;
                    nuevaVentanaModal = modalPoda();
                    nuevaVentanaModal.setPrincipalApp(app, app.best(1).cylinder);
                    nuevaVentanaModal.num_qsm = 1;
                    titulo = sprintf('Propuesta de poda %d', 1);
                    nuevaVentanaModal.Propuestadepoda1ButtonGroup.Title = titulo;
                    nuevaVentanaModal.Primarias.Value = double(valores_verdad(1));
                    nuevaVentanaModal.Disvertical.Value = double(valores_verdad(2));
                    nuevaVentanaModal.Dishelicoidal.Value = double(valores_verdad(3));
                    nuevaVentanaModal.Luz.Value = double(valores_verdad(4));
                    nuevaVentanaModal.Finas.Value = double(valores_verdad(5));
                    nuevaVentanaModal.Angulos.Value = double(valores_verdad(6));
                    nuevaVentanaModal.Volpodado.Value = double(valores_verdad(7));
            end

        end

        
        function get_points_DM(app)

            app.metas(1).apertura = [app.AjusteAperturaAlto.Value, app.AjusteAperturaInd.Value, app.AjusteAperturaEvi.Value];
            app.metas(1).disVertical = [app.AjusteDisverticalAlto.Value, app.AjusteDisverticalInd.Value, app.AjusteDisverticalEvi.Value];
            app.metas(1).LAI = [app.AjusteLuzAlto.Value, app.AjusteLuzInd.Value, app.AjusteLuzEvi.Value];
            app.metas(1).primarias = [app.AjusteprimariasAlto.Value, app.AjusteprimariasInd.Value, app.AjusteprimariasEvi.Value];
            app.metas(1).helicoidal = [app.AjusteDishelicoidalAlto.Value, app.AjusteDishelicoidalInd.Value, app.AjusteDishelicoidalEvi.Value];
            app.metas(1).madera = [app.AjusteVolumenmaderaAlto.Value, app.AjusteVolumenmaderaInd.Value, app.AjusteVolumenmaderaEvi.Value];
            app.metas(1).finas = [app.AjusteRamasfinasAlto.Value, app.AjusteRamasfinasInd.Value, app.AjusteRamasfinasEvi.Value];

        end
        
        function metas_lider_central(app, cant_primary, cant_finas)
            % estructura de las metas = [ideal, indiferente, nadir]

            app.metas(1).apertura = [0, 25, cant_primary * 25];
            app.metas(1).disVertical = [0, 20, 35];
            app.metas(1).LAI = [2, 3, 6];
            app.metas(1).primarias = [10, 5, 3];
            app.metas(1).helicoidal = [0, 70, 110];
            app.metas(1).madera = [30, 10, 0];
            app.metas(1).finas = [cant_finas, floor(cant_finas/2), floor(cant_finas/3)];
            

            app.AjusteAperturaAlto.Value = app.metas(1).apertura(1);
            app.AjusteAperturaInd.Value = app.metas(1).apertura(2);
            app.AjusteAperturaEvi.Value = app.metas(1).apertura(3);
            app.AjusteDisverticalAlto.Value = app.metas(1).disVertical(1);
            app.AjusteDisverticalInd.Value = app.metas(1).disVertical(2);
            app.AjusteDisverticalEvi.Value = app.metas(1).disVertical(3);
            app.AjusteLuzAlto.Value = app.metas(1).LAI(1);
            app.AjusteLuzInd.Value = app.metas(1).LAI(2);
            app.AjusteLuzEvi.Value = app.metas(1).LAI(3);
            app.AjusteprimariasAlto.Value = app.metas.primarias(1);
            app.AjusteprimariasInd.Value = app.metas.primarias(2);
            app.AjusteprimariasEvi.Value = app.metas.primarias(3);
            app.AjusteDishelicoidalAlto.Value = app.metas.helicoidal(1);
            app.AjusteDishelicoidalInd.Value = app.metas.helicoidal(2);
            app.AjusteDishelicoidalEvi.Value = app.metas.helicoidal(3);
            app.AjusteVolumenmaderaAlto.Value = app.metas.madera(1);
            app.AjusteVolumenmaderaInd.Value = app.metas.madera(2);
            app.AjusteVolumenmaderaEvi.Value = app.metas.madera(3);
            app.AjusteRamasfinasAlto.Value = app.metas.finas(1);
            app.AjusteRamasfinasInd.Value = app.metas.finas(2);
            app.AjusteRamasfinasEvi.Value = app.metas.finas(3);

        end
        
        function dis_lambda = distancia_proporcional(app, lambda, ideal, nadir)
            dis_lambda = (lambda - ideal) ./ (nadir - ideal);
        end
        
        function update_parametros(app)

            app.parametros(1).nadir = [app.metas.primarias(3), app.metas.disVertical(3), app.metas.helicoidal(3), app.metas.LAI(3), app.metas.finas(3), app.metas.apertura(3), app.metas.madera(3)];
            app.parametros(1).ideal = [app.metas.primarias(1), app.metas.disVertical(1), app.metas.helicoidal(1), app.metas.LAI(1), app.metas.finas(1), app.metas.apertura(1), app.metas.madera(1)];
            if app.phase == 0
                app.parametros(1).lambda = [app.metas.primarias(1), app.metas.disVertical(2), app.metas.helicoidal(2), app.metas.LAI(1), app.metas.finas(2), app.metas.apertura(2), app.metas.madera(1)];
                t99 = [app.metas.primarias(3), app.metas.disVertical(1), app.metas.helicoidal(1), app.metas.LAI(3), app.metas.finas(1), app.metas.apertura(1), app.metas.madera(3)];
                app.parametros(1).m = [.5, 0, 0, .5, 1, 0, .5];
                app.parametros(1).alfa = calcular_alpha(app, app.parametros(1).lambda, t99);
                app.parametros(1).M = calcular_M(app, app.parametros.m);
            else
                % esta es la phase 1, 2 y 3
                lambda = [app.metas.primarias(2), app.metas.disVertical(2), app.metas.helicoidal(2), app.metas.LAI(2), app.metas.finas(2), app.metas.apertura(2), app.metas.madera(2)];
                app.parametros(1).lambda = distancia_proporcional(app, lambda, app.parametros.ideal, app.parametros.nadir);
                t99 = [.01, .5]; % [clossnes, farness]
                app.parametros(1).m = [0, 1]; % [clossnes, farness]
                app.parametros(1).alfa = calcular_alpha(app, app.parametros(1).lambda, t99(1));
                app.parametros(1).alfa_far = calcular_alpha(app, app.parametros(1).lambda, t99(2));
                app.parametros(1).M = calcular_M(app, app.parametros.m);

                % esta es la phase 3
                if app.phase == 3 || app.phase == 4

                    app.parametros(1).prioridad = zeros(1, app.objetivos);
                    for i = 1:numel(app.chechboxes)
                        checkBoxActual = app.chechboxes{i};
                        app.parametros(1).prioridad(i) = checkBoxActual.Value;
                        if app.phase == 4
                            checkBoxActual.Value = false;
                        end
                    end

                 elseif app.phase == 5

                     app.parametros(1).max_prioridad = zeros(1, app.objetivos);
                      for i = 1:numel(app.chechboxes)
                        checkBoxActual = app.chechboxes{i};
                        app.parametros(1).max_prioridad(i) = checkBoxActual.Value;

                        if app.parametros(1).prioridad(i) && checkBoxActual.Value
                            app.parametros(1).prioridad(i) = false;
                        end

                        checkBoxActual.Value = false;

                      end

                 end

            end

        end

        
        function miMsgboxPersonalizado(app, mensaje, fase)

            titulo = sprintf('Fase %d completada', fase);

            % Crear la ventana
            f = uifigure('Name', titulo, ...
                'Position', [500 500 500 300], ... % Tamaño más amplio
                'Color', [.93, .90, .84], ...
                'Resize', 'off');
        
            % Agregar el texto alineado a la izquierda
            uilabel(f, ...
                'Text', mensaje, ...
                'FontSize', 14, ...
                'FontWeight', 'bold', ...
                'FontName', 'Segoe UI', ...
                'FontColor', [.28 .24 .24], ...
                'HorizontalAlignment', 'left', ...
                'Position', [25 90 450 160], ...
                'WordWrap', 'on');
        
            % Botón Confirmar que cierra la ventana
            uibutton(f, ...
                'Text', 'Confirmar', ...
                'Position', [190 20 120 30], ...
                'FontSize', 16, ...
                'FontWeight', 'bold', ...
                'FontColor', [.28 .24 .24], ...
                'BackgroundColor', [.77 .85 .64], ...
                'ButtonPushedFcn', @(btn, event) close(f));

        end
        
        function close_to_true = closeness_to_true(app, predicado)
            close_to_true = false;

            if .9 <= predicado
                close_to_true = true;
            end

        end


        function change_preferences(app)

            % primero verificar si hay solucioes en la elite, si no usar
            % best

            if isempty(app.Eliteants)
                bestglobal = app.best(1);
            else
                bestglobal = app.Eliteants(1);
            end


            if app.phase == 0

                
                % en esta fase la mdoificacion es por ruleta
                probabilidades = bestglobal.valor_verdad;
                valores_objetivos = bestglobal.valor_objetivos;
                
                
                for i = 1 : numel(valores_objetivos)

                    %generar numero aleatorio
                    r = rand;

                    %si el numero es mayor, se modifica objetivo

                    if r > probabilidades(i)
                        
                        t = valores_objetivos(i);
                        ideal = app.metas(1).(app.nombresObjetivos{i})(1);
                        nadir = app.metas(1).(app.nombresObjetivos{i})(3);
                        ajuste_maximo = 2;
                        ideal_nuevo = 0;
                        distancia = distancia_proporcional(app, t, ideal, nadir);

                        score = max(0, min(1, distancia));

                        %ajuste del ideal
                        delta_ideal = round(score * ajuste_maximo);

                        if t > ideal

                            ideal_nuevo = ideal + delta_ideal;

                        elseif t < ideal

                            ideal_nuevo = ideal - delta_ideal;

                        else

                            ideal_nuevo = ideal;

                        end

                        app.metas(1).(app.nombresObjetivos{i})(1) = ideal_nuevo;
                        app.(app.nombresCamposIdeal{i}).Value = double(ideal_nuevo);

                    end

                end


            elseif app.phase == 1
                
                valores_verdad = bestglobal.valor_verdad;
                indices = find(valores_verdad < 0.833);
                valores_objetivos = bestglobal.valor_objetivos;

                for i = 1 : numel(indices)
                    indice = indices(i);
                    t = valores_objetivos(indice);
                    ideal = app.metas(1).(app.nombresObjetivos{indice})(1);
                    nadir = app.metas(1).(app.nombresObjetivos{indice})(3);
                    ajuste_maximo = 1;
                    ideal_nuevo = 0;
                    distancia = distancia_proporcional(app, t, ideal, nadir);

                    score = max(0, min(1, distancia));

                    %ajuste del ideal
                    delta_ideal = round(score * ajuste_maximo);

                    if t > ideal

                        ideal_nuevo = ideal + delta_ideal;

                    elseif t < ideal

                        ideal_nuevo = ideal - delta_ideal;

                    else

                        ideal_nuevo = ideal;

                    end

                    app.metas(1).(app.nombresObjetivos{indice})(1) = ideal_nuevo;
                    app.(app.nombresCamposIdeal{indice}).Value = double(ideal_nuevo);

                end

            elseif app.phase == 2

                valores_verdad = bestglobal.valor_verdad;
                indices = find(valores_verdad < 0.9);
                % app.parametros(1).prioridad(indices) = true;

                for i = 1:length(indices)
                    app.chechboxes{indices(i)}.Value = true;
                end


            elseif app.phase == 4

                indices = find(app.parametros(1).prioridad);
                valores_verdad = bestglobal.valor_verdad(indices);
                [~, idx_local] = min(valores_verdad);
                if ~isempty(idx_local)
                    idx_global = indices(idx_local);
                    % app.parametros(1).max_prioridad(idx_local) = true;
                    app.chechboxes{idx_global}.Value = true;
                end


            end


        end
        
        function [probabilidad, a, b] = getProbabilidadcorte(app, array_order)
              
                array_order_branch = array_order(array_order ~= 0);
            
                % Cálculo de probabilidades
                orders = unique(array_order_branch);
                sum_orders = sum(orders);
                conteo = histcounts(array_order_branch, orders);
                pesos = double(orders) ./ sum_orders;
                probabilidad = pesos ./ conteo;
                
                a = probabilidad(1) - 0.00020;
                b = probabilidad(end) + 0.01;
        end
        
        function [validos, pesos_validos] = getProbabilidadSeleccionar(app, array_order)
            pesos = array_order;
            pesos = pesos(:);

            % crear vector de indices
            indices = 1:length(pesos);

            % filtrar los que son 0
            validos = indices(pesos > 0);
            pesos_validos = pesos(pesos > 0);
        end
    end
    
    methods (Access = private)
        
        function NOACO(app, volumen_tree_original, pesos_cuts, valores, validos, pesos_validos)
            app.iter = 0;
            app.rep = 0;
            lastNSlocal = [];
            % aqui comienza el ciclo de optimizacion
            while app.iter < app.iter_max
                disp(['Inicia poblacion ' num2str(app.iter + 1)]);
                [app.hormigas, app.cortes] = gen_sol(app.Nogal_Inicial, app.feromona_cortes, app.feromona, app.n_hormigas, app.cant_cilindros, app.matriz_dependencias, app.a1, app.a2, app.w, pesos_cuts, valores, validos, pesos_validos);
                app.QSM_hormigas_info = treeqsmNogal(app.P,app.inputs, app.n_hormigas, app.hormigas, app.Nogal_Inicial.segment2, app.Nogal_Inicial.cover2, app.Nogal_Inicial.cylinder);
                app.O = obtener_info_soluciones(volumen_tree_original, app.QSM_hormigas_info, app.phase, app.parametros, app.hormigas, app.cortes, app.n_hormigas);
                evaporar_feromona(app);
                app.NSlocal = obtener_ns_local(app.O, app.NSlocal, app.n_hormigas);
                intensificar_feromona(app, app.NSlocal);
                app.Eliteants = obtener_Elite(app.NSlocal, app.Eliteants, app.n_hormigas);

                % cantidad de veces que se repite best
                if isequal(app.best, app.NSlocal(1)) || app.best.predicado == app.NSlocal(1).predicado || isequal(lastNSlocal, app.NSlocal(1))
                    app.rep = app.rep + 1;
                elseif app.best.predicado < app.NSlocal(1).predicado
                    app.rep = 0;
                    app.best = app.NSlocal(1);
                end

                % borrar el conjunto elite de NSlocal
                if app.rep == app.rep_max
                    disp('ya alcanzo el max de reps');
                    app.rep = 0;
                    app.NSlocal = remove_elite(app.NSlocal);
                end
                for n = 1 : 1
                    disp(app.NSlocal(n).predicado);
                    disp(app.NSlocal(n).puntos_corte);
                end
                lastNSlocal = app.NSlocal(1);
                app.iter = app.iter + 1;

            end

        end
        
        function M = calcular_M(app, m)

            M = (m .^ m) .* ((1-m) .^ (1-m));

        end
        
        function alfa = calcular_alpha(app, lambda, t99)

            alfa = log(99) ./ abs((t99 - lambda));

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: seleccinoarArchivo
        function seleccinoarArchivoClicked(app, event)
            % Validar si el archivo ya está asignado
            if isempty(app.nombre_archivo) || ~ischar(app.nombre_archivo) && ~isstring(app.nombre_archivo)
                % Solicitar al usuario seleccionar un archivo
                [file, path] = uigetfile('*.las', 'Seleccione un archivo LAS');
                
                if isequal(file, 0)
                    % El usuario canceló la selección del archivo
                    disp('No se seleccionó ningún archivo.');
                    return;
                end
                
                % Asignar el archivo seleccionado
                app.nombre_archivo = fullfile(path, file);
            end
            
            % Intentar usar lasFileReader con el archivo válido
            try
                lasReader = lasFileReader(app.nombre_archivo);
                disp('Archivo leído exitosamente.');
            catch ME
                disp(['Error al leer el archivo: ', ME.message]);
            end
            %[filename, pathname] = uigetfile({'*.las'}, 'File Selector');
            %app.nombre_archivo = strcat(filename);
            %lasReader = lasFileReader(app.nombre_archivo);
            ptCloud = readPointCloud(lasReader);
            scatter3(app.AxesNube, ptCloud.Location(:,1), ptCloud.Location(:,2), ptCloud.Location(:,3), 0.5,'MarkerEdgeColor', "#614124",'MarkerFaceColor',"#614124");
        end

        % Callback function: salir
        function salirClicked(app, event)
            close(app.UIFigure);

        end

        % Button pushed function: IniciarpodaButton
        function IniciarpodaButtonPushed(app, event)
            % positions = [50,350,380,230; 540,350,380,230; 1030,350,380,230];
            % inicio del algoritmo de optimizacion de la poda de nogales
            
            app.chechboxes = {app.CheckPrimarias, app.CheckDisVert, app.CheckDisHeli, app.CheckLuz, app.CheckRamasFinas, app.CheckApertura, app.CheckMadera};
            app.nombresObjetivos = {'primarias', 'disVertical', 'helicoidal', 'LAI', 'finas', 'apertura', 'madera'};
            app.nombresCamposIdeal = {'AjusteprimariasAlto', 'AjusteDisverticalAlto', 'AjusteDishelicoidalAlto', 'AjusteLuzAlto', 'AjusteRamasfinasAlto', 'AjusteAperturaAlto', 'AjusteVolumenmaderaAlto'};
            lasReader = lasFileReader(app.nombre_archivo);
            ptCloud = readPointCloud(lasReader);
            app.P = ptCloud.Location;
            app.P = app.P-mean(app.P);
            app.inputs = define_input(app.P,1,1,1);
            app.Nogal_Inicial = treeqsm(app.P, app.inputs, 1, []);
            NumberBranches = app.Nogal_Inicial(1).treedata.NumberBranches;

            % generar probabilidad de cortes deseado
            valores = 1:NumberBranches;
            tercio_ramas = floor(NumberBranches/3);
            limite_alta = min(10, NumberBranches);
            zona1 = 1:limite_alta;
            pesos1 = ones(1, numel(zona1)) * 5;
            zona2 = (limite_alta+1):min(tercio_ramas, NumberBranches);
            n2 = numel(zona2);
        
            if n2 > 0
                pesos2 = linspace(3, 1, n2);  % Probabilidad media decreciente
            else
                pesos2 = [];
            end
        
            zona3 = (tercio_ramas+1):NumberBranches;
            n3 = numel(zona3);
            if n3 > 0
                pesos3 = linspace(0.5, 0.1, n3);  % Probabilidad baja decreciente
            else
                pesos3 = [];
            end
        
            pesos_cuts = [pesos1, pesos2, pesos3];
        
            % Asegurar que longitudes coinciden
            assert(numel(pesos_cuts) == numel(valores), 'Los pesos no coinciden con el rango de valores.');
        
            % Normalizar
            pesos_cuts = pesos_cuts / sum(pesos_cuts);

            app.matriz_dependencias = construir_matriz_dependencia(app.Nogal_Inicial.cylinder);
            plot_cylinder_model(app.Nogal_Inicial(1).cylinder, 'order', app.AxesNube, 10);
            volumen_tree_original = app.Nogal_Inicial(1).treedata.TotalVolume;
            branches_data = app.Nogal_Inicial(1).branch;
            ramas_finas = find(branches_data.order > 1 & branches_data.length > .6);
            ramas_primarias = find(branches_data.order == 1);
            cant_finas = length(ramas_finas);
            cant_primarias = length(ramas_primarias);
            array_order = app.Nogal_Inicial.cylinder.BranchOrder;
            app.cant_cilindros = length(array_order);

            % get probabilidad de cortar 
            [probabilidad_de_cortar, a, b] = getProbabilidadcorte(app, array_order);

            % get probabilidad de seleccion
            [validos, pesos_validos] = getProbabilidadSeleccionar(app, array_order);

            for k = 1 : 30
            tic;  % Inicia el cronómetro
            disp(['Inicia prueba ' num2str(k)]);
            disp('');
            prueba = sprintf('prueba%d', k);
            cellfun(@(c) set(c, 'Value', false), app.chechboxes);
            app.iter = 0;
            app.rep = 0;
            app.phase = 0;
            app.Eliteants = struct('valor_objetivos', {}, 'valor_verdad', {}, 'predicado', {}, 'predicado_round', {},'ant', {}, 'puntos_corte', {}, 'cortes', {}, 'cylinder',{}, 'treeData', {}, 'branchData', {});
            app.NSlocal = struct('valor_objetivos', {}, 'valor_verdad', {}, 'predicado', {}, 'predicado_round', {},'ant', {}, 'puntos_corte', {}, 'cortes', {}, 'cylinder',{}, 'treeData', {}, 'branchData', {});
            app.parametros(1).prioridad = zeros(1, app.objetivos);
            app.best = [];
            app.w = app.W * rand(app.n_hormigas * 5, 1);
            app.feromona = ones(length(app.Nogal_Inicial.cylinder.branch));
            app.feromona_cortes = ones(1, NumberBranches);
            metas_lider_central(app, cant_primarias,cant_finas);
            update_parametros(app);
            [app.hormigas, app.cortes] = Generar_Poblacion_I(app.matriz_dependencias, app.n_hormigas, app.cant_cilindros, array_order, pesos_cuts, valores, probabilidad_de_cortar, a, b);
            %[app.hormigas, app.cortes] = Generar_Poblacion_I2(app);
            app.QSM_hormigas_info = treeqsmNogal(app.P,app.inputs, app.n_hormigas, app.hormigas, app.Nogal_Inicial.segment2, app.Nogal_Inicial.cover2, app.Nogal_Inicial.cylinder);
            app.O = obtener_info_soluciones(volumen_tree_original, app.QSM_hormigas_info, app.phase, app.parametros, app.hormigas, app.cortes, app.n_hormigas);
            app.NSlocal = obtener_ns_local(app.O, app.NSlocal, app.n_hormigas);
            app.best = app.NSlocal(1); 
            intensificar_feromona(app, app.NSlocal);
            mejor_predicado = zeros(1, 6);
            
            % comienza la optimizacion por fases
            while app.phase <= 0

                disp(['Se encuentra en la fase: ' num2str(app.phase)]);
                NOACO(app, volumen_tree_original, pesos_cuts, valores, validos, pesos_validos);
                % graficar(app); % Graficar para interactuar con el DM
                % disp(['esto es best de phase: ' num2str(app.phase)]);
                % disp(app.best(1));
                mejor_predicado(app.phase + 1) = app.best(1).predicado;

                if app.phase == 0

                    mensaje = "Revisa las soluciones mostradas." + newline + ...
                               newline + ...
                              "Si ninguna es adecuada, modifica los valores ideales antes de avanzar a la fase 1.";
                    
                elseif app.phase == 1

                    mensaje =   "Revisa las soluciones mostradas." + newline + ...
                                newline + ...
                                "Si ninguna es adecuada, puedes (antes de avanzar a la fase 2):" + newline + ...
                                newline + ...
                                "  1. Modificar los valores ideales según lo que buscas para esta estructura de poda." + newline + ...
                                newline + ...
                                "  2. Continuar buscando soluciones, haciendo la búsqueda menos estricta.";
                   

                elseif app.phase == 2

                    mensaje =   "Revisa las soluciones mostradas." + newline + ...
                                newline + ...
                                "Si ninguna es adecuada, selecciona los objetivos que tienen prioridad antes de avanzar a la fase 3";
                    

                elseif app.phase == 3

                    mensaje =   "Revisa las soluciones mostradas." + newline + ...
                                newline + ...
                                "Si ninguna es adecuada, se continua buscando soluciones, haciendo la búsqueda más estricta para la fase 4";

                elseif app.phase == 4

                    mensaje =   "Revisa las soluciones mostradas." + newline + ...
                                newline + ...
                                "Si ninguna es adecuada, selecciona los objetivos que tienen mayor prioridad antes de avanzar a la fase 5";
                    
                elseif app.phase == 5

                    mensaje =   "Se debe seleccioanr una estructura de poda";

                end

                % miMsgboxPersonalizado(app, mensaje, app.phase);
                % uiwait(app.UIFigure);

                % ✅ Verificamos que la ventana sigue abierta
                if ~isvalid(app) || ~isvalid(app.UIFigure)
                    disp("⚠️ La ventana fue cerrada. Se interrumpe el proceso de poda.");
                    break;
                end

                %get_points_DM(app); % se obtiene el punto ideal, indiferente y nadir del DM
                % change_preferences(app); % cambiar el valor del ideal de lo criterios
                app.phase = app.phase + 1;
                update_parametros(app);

            end
            tiempo = toc;

            if ~isempty(app.Eliteants)
                elite = app.Eliteants;
            else
                elite = app.best;
            end

            % disp('conjunto elite');
            % for e = 1 : numel(elite)
            %     disp(elite(e));
            % end
            %graficar(app);
            save_nogal_inicial(app.Nogal_Inicial, app.nombre_archivo);
            save_test(elite, app.nombre_archivo, prueba, mejor_predicado, tiempo, app.parametros.prioridad, app.parametros.max_prioridad);

            end
            
            disp('Termian algoritmo');
            % disp(mejor_predicado);
 
        end

        % Button pushed function: ContinuarButton
        function ContinuarButtonPushed(app, event)
             % Continuar para capturar las metas del algoritmo
             uiresume(app.UIFigure);
        end

        % Button pushed function: FinalizarButton
        function FinalizarButtonPushed(app, event)
            % Capturar el contenido de la figura principal
            frame = getframe(app.UIFigure);
    
            % Convertir el frame a una imagen
            img = frame.cdata;
    
            % Guardar la imagen como un archivo PNG
            imwrite(img, 'interfaz_capturada.png');
            msgbox('Se ha guardado la propuesta de poda: ' + string(app.seleccionado), "Success", 'help');

            app.iter = 0;
            app.rep = 0;
            app.phase = 0;
            app.Eliteants = struct('valor_objetivos', {}, 'valor_verdad', {}, 'predicado', {}, 'predicado_round', {},'ant', {}, 'puntos_corte', {}, 'cortes', {}, 'cylinder',{}, 'treeData', {}, 'branchData', {});
            app.NSlocal = struct('valor_objetivos', {}, 'valor_verdad', {}, 'predicado', {}, 'predicado_round', {},'ant', {}, 'puntos_corte', {}, 'cortes', {}, 'cylinder',{}, 'treeData', {}, 'branchData', {});
            app.best = [];
            app.AjusteAperturaAlto.Value = 0;
            app.AjusteAperturaInd.Value = 0;
            app.AjusteAperturaEvi.Value = 0;
            app.AjusteDisverticalAlto.Value = 0;
            app.AjusteDisverticalInd.Value = 0;
            app.AjusteDisverticalEvi.Value = 0;
            app.AjusteLuzAlto.Value = 0;
            app.AjusteLuzInd.Value = 0;
            app.AjusteLuzEvi.Value = 0;
            app.AjusteprimariasAlto.Value = 0;
            app.AjusteprimariasInd.Value = 0;
            app.AjusteprimariasEvi.Value = 0;
            app.AjusteDishelicoidalAlto.Value = 0;
            app.AjusteDishelicoidalInd.Value = 0;
            app.AjusteDishelicoidalEvi.Value = 0;
            app.AjusteVolumenmaderaAlto.Value = 0;
            app.AjusteVolumenmaderaInd.Value = 0;
            app.AjusteVolumenmaderaEvi.Value = 0;
            app.AjusteRamasfinasAlto.Value = 0;
            app.AjusteRamasfinasInd.Value = 0;
            app.AjusteRamasfinasEvi.Value = 0;
            app.nombre_archivo = '';
            app.seleccionado = 0;
            cla(app.AxesNube);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.seleccinoarArchivo = uipushtool(app.Toolbar);
            app.seleccinoarArchivo.Tooltip = {'Seleccionar instancia'};
            app.seleccinoarArchivo.ClickedCallback = createCallbackFcn(app, @seleccinoarArchivoClicked, true);
            app.seleccinoarArchivo.Icon = fullfile(pathToMLAPP, 'iconos', 'tree (2).png');
            app.seleccinoarArchivo.Separator = 'on';
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.Tooltip = {'Guardar'};
            app.PushTool2.Icon = fullfile(pathToMLAPP, 'iconos', 'computer.png');
            app.PushTool2.Separator = 'on';
            app.salir = uipushtool(app.Toolbar);
            app.salir.Tooltip = {'Salir'};
            app.salir.ClickedCallback = createCallbackFcn(app, @salirClicked, true);
            app.salir.Icon = fullfile(pathToMLAPP, 'iconos', 'tractor.png');
            app.salir.Separator = 'on';
            app.InstanciainicialdelnogalPanel = uipanel(app.UIFigure);
            app.InstanciainicialdelnogalPanel.Title = 'Instancia inicial del nogal';
            app.AxesNube = uiaxes(app.InstanciainicialdelnogalPanel);
            app.AxesNube.XTick = [0 1 2 3 4 5 6 7 8 9 10];
            app.AxesNube.XTickLabel = '';
            app.AxesNube.YTick = [0 1 2 3 4 5 6 7 8 9 10];
            app.AxesNube.YTickLabel = '';
            app.AxesNube.ZTick = [0 1 2 3 4 5 6 7 8 9 10];
            app.Image2_7 = uiimage(app.InstanciainicialdelnogalPanel);
            app.Image2_7.ImageSource = fullfile(pathToMLAPP, 'iconos', 'tree copy.png');
            app.IniciarpodaButton = uibutton(app.InstanciainicialdelnogalPanel, 'push');
            app.IniciarpodaButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarpodaButtonPushed, true);
            app.IniciarpodaButton.Text = 'Iniciar poda';
            app.ContinuarButton = uibutton(app.InstanciainicialdelnogalPanel, 'push');
            app.ContinuarButton.ButtonPushedFcn = createCallbackFcn(app, @ContinuarButtonPushed, true);
            app.ContinuarButton.Text = 'Continuar';
            app.FinalizarButton = uibutton(app.InstanciainicialdelnogalPanel, 'push');
            app.FinalizarButton.ButtonPushedFcn = createCallbackFcn(app, @FinalizarButtonPushed, true);
            app.FinalizarButton.Text = 'Finalizar';
            app.AjustedevaloresidealesPanel = uipanel(app.UIFigure);
            app.AjustedevaloresidealesPanel.Title = 'Ajuste de valores ideales';
            app.Image8 = uiimage(app.AjustedevaloresidealesPanel);
            app.Image8.ImageSource = fullfile(pathToMLAPP, 'iconos', 'sliders-solid.svg');
            app.RamasfinasLabel = uilabel(app.AjustedevaloresidealesPanel);
            app.RamasfinasLabel.Text = 'Ramas finas';
            app.AjusteRamasfinasAlto = uieditfield(app.AjustedevaloresidealesPanel, 'numeric');
            app.AjusteRamasfinasInd = uieditfield(app.AjustedevaloresidealesPanel, 'numeric');
            app.AperturadecopaLabel = uilabel(app.AjustedevaloresidealesPanel);
            app.AperturadecopaLabel.Text = 'Apertura de copa';
            app.AjusteAperturaAlto = uieditfield(app.AjustedevaloresidealesPanel, 'numeric');
            app.AjusteAperturaInd = uieditfield(app.AjustedevaloresidealesPanel, 'numeric');
            app.Image_3 = uiimage(app.AjustedevaloresidealesPanel);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'iconos', 'branch copy.png');
            app.Image_4 = uiimage(app.AjustedevaloresidealesPanel);
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'iconos', 'branch copy.png');
            app.Image_11 = uiimage(app.AjustedevaloresidealesPanel);
            app.Image_11.ImageSource = fullfile(pathToMLAPP, 'iconos', 'angle.png');
            app.Image_12 = uiimage(app.AjustedevaloresidealesPanel);
            app.Image_12.ImageSource = fullfile(pathToMLAPP, 'iconos', 'angle.png');
            app.ValoridealLabel_4 = uilabel(app.AjustedevaloresidealesPanel);
            app.ValoridealLabel_4.Text = 'Valor ideal: ';
            app.ValoridealLabel_5 = uilabel(app.AjustedevaloresidealesPanel);
            app.ValoridealLabel_5.Text = 'Valor ideal: ';
            app.ValorindiferenteLabel_4 = uilabel(app.AjustedevaloresidealesPanel);
            app.ValorindiferenteLabel_4.Text = 'Valor indiferente:';
            app.ValorindiferenteLabel_5 = uilabel(app.AjustedevaloresidealesPanel);
            app.ValorindiferenteLabel_5.Text = 'Valor indiferente:';
            app.AjusteRamasfinasEvi = uieditfield(app.AjustedevaloresidealesPanel, 'numeric');
            app.AjusteAperturaEvi = uieditfield(app.AjustedevaloresidealesPanel, 'numeric');
            app.Image_48 = uiimage(app.AjustedevaloresidealesPanel);
            app.Image_48.ImageSource = fullfile(pathToMLAPP, 'iconos', 'branch copy.png');
            app.Image_49 = uiimage(app.AjustedevaloresidealesPanel);
            app.Image_49.ImageSource = fullfile(pathToMLAPP, 'iconos', 'angle.png');
            app.ValoraevitarLabel_4 = uilabel(app.AjustedevaloresidealesPanel);
            app.ValoraevitarLabel_4.Text = 'Valor a evitar:';
            app.ValoraevitarLabel_5 = uilabel(app.AjustedevaloresidealesPanel);
            app.ValoraevitarLabel_5.Text = 'Valor a evitar:';
            app.DistribucinverticalLabel = uilabel(app.AjustedevaloresidealesPanel);
            app.DistribucinverticalLabel.Text = 'Distribución vertical';
            app.AjusteDisverticalAlto = uieditfield(app.AjustedevaloresidealesPanel, 'numeric');
            app.AjusteDisverticalInd = uieditfield(app.AjustedevaloresidealesPanel, 'numeric');
            app.Image_5 = uiimage(app.AjustedevaloresidealesPanel);
            app.Image_5.ImageSource = fullfile(pathToMLAPP, 'iconos', 'vertical-farming.png');
            app.Image_6 = uiimage(app.AjustedevaloresidealesPanel);
            app.Image_6.ImageSource = fullfile(pathToMLAPP, 'iconos', 'vertical-farming.png');
            app.ValoridealLabel_6 = uilabel(app.AjustedevaloresidealesPanel);
            app.ValoridealLabel_6.Text = 'Valor ideal: ';
            app.ValorindiferenteLabel_6 = uilabel(app.AjustedevaloresidealesPanel);
            app.ValorindiferenteLabel_6.Text = 'Valor indiferente:';
            app.AjusteDisverticalEvi = uieditfield(app.AjustedevaloresidealesPanel, 'numeric');
            app.Image_51 = uiimage(app.AjustedevaloresidealesPanel);
            app.Image_51.ImageSource = fullfile(pathToMLAPP, 'iconos', 'vertical-farming.png');
            app.ValoraevitarLabel_7 = uilabel(app.AjustedevaloresidealesPanel);
            app.ValoraevitarLabel_7.Text = 'Valor a evitar:';
            app.LAILabel = uilabel(app.AjustedevaloresidealesPanel);
            app.LAILabel.Text = 'LAI';
            app.AjusteLuzAlto = uieditfield(app.AjustedevaloresidealesPanel, 'numeric');
            app.AjusteLuzInd = uieditfield(app.AjustedevaloresidealesPanel, 'numeric');
            app.Image_9 = uiimage(app.AjustedevaloresidealesPanel);
            app.Image_9.ImageSource = fullfile(pathToMLAPP, 'iconos', 'sun.png');
            app.Image_10 = uiimage(app.AjustedevaloresidealesPanel);
            app.Image_10.ImageSource = fullfile(pathToMLAPP, 'iconos', 'sun.png');
            app.ValoridealLabel = uilabel(app.AjustedevaloresidealesPanel);
            app.ValoridealLabel.Text = 'Valor ideal: ';
            app.ValorindiferenteLabel_3 = uilabel(app.AjustedevaloresidealesPanel);
            app.ValorindiferenteLabel_3.Text = 'Valor indiferente:';
            app.AjusteLuzEvi = uieditfield(app.AjustedevaloresidealesPanel, 'numeric');
            app.Image_47 = uiimage(app.AjustedevaloresidealesPanel);
            app.Image_47.ImageSource = fullfile(pathToMLAPP, 'iconos', 'sun.png');
            app.ValoraevitarLabel_3 = uilabel(app.AjustedevaloresidealesPanel);
            app.ValoraevitarLabel_3.Text = 'Valor a evitar:';
            app.CheckRamasFinas = uicheckbox(app.AjustedevaloresidealesPanel);
            app.CheckRamasFinas.Tooltip = {'Selecciona si uno de los objetivos que más importa en esta ocasión'};
            app.CheckApertura = uicheckbox(app.AjustedevaloresidealesPanel);
            app.CheckApertura.Tooltip = {'Selecciona si uno de los objetivos que más importa en esta ocasión'};
            app.CheckDisVert = uicheckbox(app.AjustedevaloresidealesPanel);
            app.CheckDisVert.Tooltip = {'Selecciona si uno de los objetivos que más importa en esta ocasión'};
            app.CheckLuz = uicheckbox(app.AjustedevaloresidealesPanel);
            app.CheckLuz.Tooltip = {'Selecciona si uno de los objetivos que más importa en esta ocasión'};
            app.RamasprimariasLabel = uilabel(app.AjustedevaloresidealesPanel);
            app.RamasprimariasLabel.Text = 'Ramas primarias';
            app.AjusteprimariasAlto = uieditfield(app.AjustedevaloresidealesPanel, 'numeric');
            app.Image = uiimage(app.AjustedevaloresidealesPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'iconos', 'branch.png');
            app.AjusteprimariasInd = uieditfield(app.AjustedevaloresidealesPanel, 'numeric');
            app.Image_2 = uiimage(app.AjustedevaloresidealesPanel);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'iconos', 'branch.png');
            app.ValoridealLabel_3 = uilabel(app.AjustedevaloresidealesPanel);
            app.ValoridealLabel_3.Text = 'Valor ideal: ';
            app.ValorindiferenteLabel = uilabel(app.AjustedevaloresidealesPanel);
            app.ValorindiferenteLabel.Text = 'Valor indiferente:';
            app.AjusteprimariasEvi = uieditfield(app.AjustedevaloresidealesPanel, 'numeric');
            app.Image_45 = uiimage(app.AjustedevaloresidealesPanel);
            app.Image_45.ImageSource = fullfile(pathToMLAPP, 'iconos', 'branch.png');
            app.ValoraevitarLabel = uilabel(app.AjustedevaloresidealesPanel);
            app.ValoraevitarLabel.Text = 'Valor a evitar:';
            app.CheckPrimarias = uicheckbox(app.AjustedevaloresidealesPanel);
            app.CheckPrimarias.Tooltip = {'Selecciona si uno de los objetivos que más importa en esta ocasión'};
            app.DistribucinhelicoidalLabel = uilabel(app.AjustedevaloresidealesPanel);
            app.DistribucinhelicoidalLabel.Text = 'Distribución helicoidal';
            app.CheckDisHeli = uicheckbox(app.AjustedevaloresidealesPanel);
            app.CheckDisHeli.Tooltip = {'Selecciona si uno de los objetivos que más importa en esta ocasión'};
            app.AjusteDishelicoidalAlto = uieditfield(app.AjustedevaloresidealesPanel, 'numeric');
            app.AjusteDishelicoidalInd = uieditfield(app.AjustedevaloresidealesPanel, 'numeric');
            app.Image_7 = uiimage(app.AjustedevaloresidealesPanel);
            app.Image_7.ImageSource = fullfile(pathToMLAPP, 'iconos', '360-degrees.png');
            app.Image_8 = uiimage(app.AjustedevaloresidealesPanel);
            app.Image_8.ImageSource = fullfile(pathToMLAPP, 'iconos', '360-degrees.png');
            app.ValoridealLabel_2 = uilabel(app.AjustedevaloresidealesPanel);
            app.ValoridealLabel_2.Text = 'Valor ideal: ';
            app.ValorindiferenteLabel_2 = uilabel(app.AjustedevaloresidealesPanel);
            app.ValorindiferenteLabel_2.Text = 'Valor indiferente:';
            app.AjusteDishelicoidalEvi = uieditfield(app.AjustedevaloresidealesPanel, 'numeric');
            app.Image_46 = uiimage(app.AjustedevaloresidealesPanel);
            app.Image_46.ImageSource = fullfile(pathToMLAPP, 'iconos', '360-degrees.png');
            app.ValoraevitarLabel_2 = uilabel(app.AjustedevaloresidealesPanel);
            app.ValoraevitarLabel_2.Text = 'Valor a evitar:';
            app.AjusteVolumenmaderaAlto = uieditfield(app.AjustedevaloresidealesPanel, 'numeric');
            app.AjusteVolumenmaderaInd = uieditfield(app.AjustedevaloresidealesPanel, 'numeric');
            app.Image_13 = uiimage(app.AjustedevaloresidealesPanel);
            app.Image_13.ImageSource = fullfile(pathToMLAPP, 'iconos', 'pruning-shears.png');
            app.Image_14 = uiimage(app.AjustedevaloresidealesPanel);
            app.Image_14.ImageSource = fullfile(pathToMLAPP, 'iconos', 'pruning-shears.png');
            app.ValoridealLabel_7 = uilabel(app.AjustedevaloresidealesPanel);
            app.ValoridealLabel_7.Text = 'Valor ideal: ';
            app.ValorindiferenteLabel_7 = uilabel(app.AjustedevaloresidealesPanel);
            app.ValorindiferenteLabel_7.Text = 'Valor indiferente:';
            app.AjusteVolumenmaderaEvi = uieditfield(app.AjustedevaloresidealesPanel, 'numeric');
            app.Image_50 = uiimage(app.AjustedevaloresidealesPanel);
            app.Image_50.ImageSource = fullfile(pathToMLAPP, 'iconos', 'pruning-shears.png');
            app.ValoraevitarLabel_6 = uilabel(app.AjustedevaloresidealesPanel);
            app.ValoraevitarLabel_6.Text = 'Valor a evitar:';
            app.CheckMadera = uicheckbox(app.AjustedevaloresidealesPanel);
            app.CheckMadera.Tooltip = {'Selecciona si uno de los objetivos que más importa en esta ocasión'};
            app.VolumendemaderapodadoLabel = uilabel(app.AjustedevaloresidealesPanel);
            app.VolumendemaderapodadoLabel.Text = 'Volumen de madera podado';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HAI_Pecan_Pruner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end