classdef Hybrid_Intelligence_Pruner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Toolbar                       matlab.ui.container.Toolbar
        seleccionarArchivo            matlab.ui.container.toolbar.PushTool
        IniciarPoda                   matlab.ui.container.toolbar.PushTool
        Salir                         matlab.ui.container.toolbar.PushTool
        SeleccionarnubedepuntosPanel  matlab.ui.container.Panel
        AxesNube                      matlab.ui.control.UIAxes
        CaracteristicasdelaEstructuraLabel_2  matlab.ui.control.Label
        CaracteristicasdelaEstructuraLabel  matlab.ui.control.Label
        inf_poda                      matlab.ui.control.TextArea
        inf_inicial                   matlab.ui.control.TextArea
        Axes_Inicial                  matlab.ui.control.UIAxes
        Axes_Poda                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        nombre_archivo % nombre de la nube de puntos string
    end
    
    methods (Access = private)
        
        function  AxesNubeDataCursorClick(app, event)
            % Obtener las coordenadas del punto seleccionado
            x_selected = event.Position(1);
            y_selected = event.Position(2);
            z_selected = event.Position(3);
        
            % Mostrar las coordenadas del punto seleccionado
            disp(['Coordenadas del punto seleccionado: (', num2str(x_selected), ', ', num2str(y_selected), ', ', num2str(z_selected), ')']);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Callback function
        function SalirMenuSelected(app, event)

        end

        % Callback function: seleccionarArchivo
        function seleccionarArchivoClicked(app, event)
            [filename pathname] = uigetfile({'*.las'}, 'File Selector');
            app.nombre_archivo = strcat(filename);
            app.SeleccionarnubedepuntosPanel.Title = app.nombre_archivo;
            lasReader = lasFileReader(app.nombre_archivo);
            ptCloud = readPointCloud(lasReader);
            scatter3(app.AxesNube, ptCloud.Location(:,1), ptCloud.Location(:,2), ptCloud.Location(:,3), 1,'Marker', '.');
            % Habilitar el modo de cursor de datos
                datacursormode(app.UIFigure, 'on');
                dcm_obj = datacursormode(app.UIFigure);
                set(dcm_obj,'UpdateFcn', @displayDatatipInfo);
        end

        % Callback function: IniciarPoda
        function IniciarPodaClicked(app, event)
            lasReader = lasFileReader(app.nombre_archivo);
            ptCloud = readPointCloud(lasReader);
            P = ptCloud.Location;
            P = P-mean(P);
            inputs = define_input(P,1,1,1);
            QSM = treeqsm(P,inputs, app.Axes_Inicial, app.Axes_Poda);
            treedata = QSM.treedata;
            units = QSM.units;
            Names = fieldnames(treedata);
            for i = 1 : 23
                app.inf_inicial.Value{i} = [Names{i}, ' = ', num2str(treedata.(Names{i})), ' ', units(i, :)];
                app.inf_poda.Value{i} = [Names{i}, ' = ', num2str(treedata.(Names{i})), ' ', units(i, :)];
            end
        end

        % Callback function: Salir
        function SalirClicked(app, event)
            close(app.UIFigure);
        end

        % Callback function
        function AxesNubeButtonDown(app, event)
            % Obtener las coordenadas del punto donde se hizo clic
            point = get(app.AxesNube, 'CurrentPoint');
    
            % Extraer las coordenadas x, y, z del punto
            x = point(1,1);
            y = point(1,2);
            z = point(1,3);
    
            % Mostrar las coordenadas en la ventana de comandos
            disp(['Coordenadas del punto clickeado: (', num2str(x), ', ', num2str(y), ', ', num2str(z), ')']);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.seleccionarArchivo = uipushtool(app.Toolbar);
            app.seleccionarArchivo.Tooltip = {'Cagar archivo'};
            app.seleccionarArchivo.ClickedCallback = createCallbackFcn(app, @seleccionarArchivoClicked, true);
            app.seleccionarArchivo.Icon = fullfile(pathToMLAPP, 'iconos', 'papel.png');
            app.seleccionarArchivo.Separator = 'on';
            app.IniciarPoda = uipushtool(app.Toolbar);
            app.IniciarPoda.Tooltip = {'Iniciar Poda'};
            app.IniciarPoda.ClickedCallback = createCallbackFcn(app, @IniciarPodaClicked, true);
            app.IniciarPoda.Icon = fullfile(pathToMLAPP, 'iconos', 'pines.png');
            app.IniciarPoda.Separator = 'on';
            app.Salir = uipushtool(app.Toolbar);
            app.Salir.Tooltip = {'Salir del programa'};
            app.Salir.ClickedCallback = createCallbackFcn(app, @SalirClicked, true);
            app.Salir.Icon = fullfile(pathToMLAPP, 'iconos', 'door-open-solid.svg');
            app.Salir.Separator = 'on';
            app.Axes_Poda = uiaxes(app.UIFigure);
            title(app.Axes_Poda, 'Sugerencia de Poda')
            app.Axes_Poda.XTick = [0 1 2 3 4 5 6 7 8 9 10];
            app.Axes_Poda.XTickLabel = '';
            app.Axes_Poda.YTick = [0 1 2 3 4 5 6 7 8 9 10];
            app.Axes_Poda.YTickLabel = '';
            app.Axes_Poda.ZTick = [0 1 2 3 4 5 6 7 8 9 10];
            app.Axes_Poda.ZTickLabel = '';
            app.Axes_Inicial = uiaxes(app.UIFigure);
            title(app.Axes_Inicial, 'Estructura Inicial del Nogal')
            app.Axes_Inicial.XTick = [0 1 2 3 4 5 6 7 8 9 10];
            app.Axes_Inicial.XTickLabel = '';
            app.Axes_Inicial.YTick = [0 1 2 3 4 5 6 7 8 9 10];
            app.Axes_Inicial.YTickLabel = '';
            app.Axes_Inicial.ZTick = [0 1 2 3 4 5 6 7 8 9 10];
            app.Axes_Inicial.ZTickLabel = '';
            app.inf_inicial = uitextarea(app.UIFigure);
            app.inf_inicial.Editable = 'off';
            app.inf_poda = uitextarea(app.UIFigure);
            app.inf_poda.Editable = 'off';
            app.CaracteristicasdelaEstructuraLabel = uilabel(app.UIFigure);
            app.CaracteristicasdelaEstructuraLabel.Text = 'Caracteristicas de la Estructura';
            app.CaracteristicasdelaEstructuraLabel_2 = uilabel(app.UIFigure);
            app.CaracteristicasdelaEstructuraLabel_2.Text = 'Caracteristicas de la Estructura';
            app.SeleccionarnubedepuntosPanel = uipanel(app.UIFigure);
            app.SeleccionarnubedepuntosPanel.Title = 'Seleccionar nube de puntos';
            app.AxesNube = uiaxes(app.SeleccionarnubedepuntosPanel);
            app.AxesNube.XTick = [];
            app.AxesNube.YTick = [];
            app.AxesNube.ZTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Hybrid_Intelligence_Pruner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end