classdef PSDP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UIAxes    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        SelectedPointHandle  matlab.graphics.chart.primitive.Scatter
    end
    
    methods (Access = public)
        
    end
    
    methods (Access = private)
        
        function openDataFile(app)
            displayDatatipInfo(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Crear el componente Axes
                % app.UIAxes.Position = [50, 50, 400, 400];

            % Parámetros del cilindro
                radio = 1; % Radio del cilindro
                altura = 3; % Altura del cilindro
                num_lados = 50; % Número de lados del cilindro

            % Coordenadas de los puntos en el círculo base
                theta = linspace(0, 2*pi, num_lados); % Ángulos
                x_base = radio * cos(theta); % Coordenadas x
                y_base = radio * sin(theta); % Coordenadas y
                z_base = zeros(size(theta)); % Coordenadas z (en el plano xy)

            % Coordenadas de los puntos en el círculo superior
                x_sup = x_base;
                y_sup = y_base;
                z_sup = altura * ones(size(theta)); % Coordenadas z (en la parte superior del cilindro)

            % Unir los puntos para formar las caras del cilindro
                vertices = [x_base', y_base', z_base'; x_sup', y_sup', z_sup'];

            % Índices de los vértices para formar las caras
                indices_base = 1:num_lados;
                indices_sup = (1:num_lados) + num_lados;
                indices_caras = [indices_base; circshift(indices_base, [0, -1]); ...
                     indices_sup; circshift(indices_sup, [0, -1])];

            % Dibujar el cilindro
                patch(app.UIAxes, 'Vertices', vertices, 'Faces', indices_caras', 'FaceColor', 'blue', 'EdgeColor', 'none');
                xlabel(app.UIAxes, 'X');
                ylabel(app.UIAxes, 'Y');
                zlabel(app.UIAxes, 'Z');
                title(app.UIAxes, 'Cilindro generado con la función patch');

            % Ajustar la vista de la cámara
                view(app.UIAxes, 3);
                camproj(app.UIAxes, 'perspective');

            % Establecer los límites de los ejes para que el cilindro sea visible
                axis(app.UIAxes, 'equal');
                axis(app.UIAxes, [-1.5*radio, 1.5*radio, -1.5*radio, 1.5*radio, 0, altura]);

            % Habilitar el modo de cursor de datos
                datacursormode(app.UIFigure, 'on');
                dcm_obj = datacursormode(app.UIFigure);
                set(dcm_obj,'UpdateFcn', @displayDatatipInfo);
        end

        % Callback function: not associated with a component
        function UIAxesButtonDown(app, event)
            % Obtener las coordenadas del punto seleccionado
                pointClicked = app.UIAxes.CurrentPoint(1, 1:3);

            % Obtener el tamaño del punto seleccionado
                markerSize = get(app.ScatterHandle, 'SizeData');
                markerSize = markerSize(1);

            % Imprimir tamaño del punto por consola
                fprintf('Tamaño del punto seleccionado: %d\n', markerSize);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PSDP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end