classdef modalFinal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        CerrarButton                matlab.ui.control.Button
        Image_6                     matlab.ui.control.Image
        LAILabel                    matlab.ui.control.Label
        Luz                         matlab.ui.control.NumericEditField
        Image_2                     matlab.ui.control.Image
        RamasfinasLabel             matlab.ui.control.Label
        Finas                       matlab.ui.control.NumericEditField
        CaracteristicasgeneralesdelnogalelegidoPanel  matlab.ui.container.Panel
        Image_19                    matlab.ui.control.Image
        Image_18                    matlab.ui.control.Image
        ElegidoDiametrocopa         matlab.ui.control.NumericEditField
        DiametrodelacopamLabel      matlab.ui.control.Label
        ElegidoVolumencopa          matlab.ui.control.NumericEditField
        VolumendelacopaLabel        matlab.ui.control.Label
        Image_14                    matlab.ui.control.Image
        ElegidoLargocopa            matlab.ui.control.NumericEditField
        LargodelacopamLabel         matlab.ui.control.Label
        Image_13                    matlab.ui.control.Image
        ElegidoLargotronco          matlab.ui.control.NumericEditField
        LargotroncomLabel           matlab.ui.control.Label
        Image_11                    matlab.ui.control.Image
        ElegidoMaxorden             matlab.ui.control.NumericEditField
        MximoordenLabel             matlab.ui.control.Label
        Image_10                    matlab.ui.control.Image
        ElegidoCantidadramas        matlab.ui.control.NumericEditField
        CantidadderamasLabel        matlab.ui.control.Label
        Image_8                     matlab.ui.control.Image
        ElegidoAltura               matlab.ui.control.NumericEditField
        AlturamLabel                matlab.ui.control.Label
        Image9                      matlab.ui.control.Image
        IndicadoresdelaestructuradepodaPanel  matlab.ui.container.Panel
        Image2                      matlab.ui.control.Image
        Image_7                     matlab.ui.control.Image
        VolumendemaderapodadoLabel  matlab.ui.control.Label
        Volpodado                   matlab.ui.control.NumericEditField
        Image_4                     matlab.ui.control.Image
        AperturadecopaLabel         matlab.ui.control.Label
        Angulos                     matlab.ui.control.NumericEditField
        Image_5                     matlab.ui.control.Image
        Image_3                     matlab.ui.control.Image
        DistribucinhelicoidalLabel  matlab.ui.control.Label
        Dishelicoidal               matlab.ui.control.NumericEditField
        DistribucinverticalLabel    matlab.ui.control.Label
        Disvertical                 matlab.ui.control.NumericEditField
        Image                       matlab.ui.control.Image
        RamasprimariasLabel         matlab.ui.control.Label
        Primarias                   matlab.ui.control.NumericEditField
        AxesPoda                    matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function activarRotacion(app, ax)
            h = rotate3d(app.UIFigure);
            h.Enable = 'on';
            axes(ax);
        end
    end
    
    methods (Access = public)
        
        function setGrafica(app, cylinder)
            plot_cylinder_model(cylinder, 'order', app.AxesPoda, 10);
            app.AxesPoda.ButtonDownFcn = @(src, event) activarRotacion(app, app.AxesPoda);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CerrarButton
        function CerrarButtonPushed(app, event)
            delete(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AxesPoda = uiaxes(app.UIFigure);
            app.AxesPoda.XTick = [0 1 2 3 4 5 6 7 8 9 10];
            app.AxesPoda.XTickLabel = '';
            app.AxesPoda.YTick = [0 1 2 3 4 5 6 7 8 9 10];
            app.AxesPoda.YTickLabel = '';
            app.AxesPoda.ZTick = [0 1 2 3 4 5 6 7 8 9 10];
            app.IndicadoresdelaestructuradepodaPanel = uipanel(app.UIFigure);
            app.IndicadoresdelaestructuradepodaPanel.Title = 'Indicadores de la estructura de poda';
            app.Primarias = uieditfield(app.IndicadoresdelaestructuradepodaPanel, 'numeric');
            app.RamasprimariasLabel = uilabel(app.IndicadoresdelaestructuradepodaPanel);
            app.RamasprimariasLabel.Text = 'Ramas primarias';
            app.Image = uiimage(app.IndicadoresdelaestructuradepodaPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'iconos', 'branch.png');
            app.Disvertical = uieditfield(app.IndicadoresdelaestructuradepodaPanel, 'numeric');
            app.DistribucinverticalLabel = uilabel(app.IndicadoresdelaestructuradepodaPanel);
            app.DistribucinverticalLabel.Text = 'Distribución vertical';
            app.Dishelicoidal = uieditfield(app.IndicadoresdelaestructuradepodaPanel, 'numeric');
            app.DistribucinhelicoidalLabel = uilabel(app.IndicadoresdelaestructuradepodaPanel);
            app.DistribucinhelicoidalLabel.Text = 'Distribución helicoidal';
            app.Image_3 = uiimage(app.IndicadoresdelaestructuradepodaPanel);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'iconos', 'vertical-farming.png');
            app.Image_5 = uiimage(app.IndicadoresdelaestructuradepodaPanel);
            app.Image_5.ImageSource = fullfile(pathToMLAPP, 'iconos', '360-degrees.png');
            app.Angulos = uieditfield(app.IndicadoresdelaestructuradepodaPanel, 'numeric');
            app.AperturadecopaLabel = uilabel(app.IndicadoresdelaestructuradepodaPanel);
            app.AperturadecopaLabel.Text = 'Apertura de copa';
            app.Image_4 = uiimage(app.IndicadoresdelaestructuradepodaPanel);
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'iconos', 'angle.png');
            app.Volpodado = uieditfield(app.IndicadoresdelaestructuradepodaPanel, 'numeric');
            app.VolumendemaderapodadoLabel = uilabel(app.IndicadoresdelaestructuradepodaPanel);
            app.VolumendemaderapodadoLabel.Text = 'Volumen de madera podado';
            app.Image_7 = uiimage(app.IndicadoresdelaestructuradepodaPanel);
            app.Image_7.ImageSource = fullfile(pathToMLAPP, 'iconos', 'pruning-shears.png');
            app.Image2 = uiimage(app.IndicadoresdelaestructuradepodaPanel);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'iconos', 'monitoring.png');
            app.CaracteristicasgeneralesdelnogalelegidoPanel = uipanel(app.UIFigure);
            app.CaracteristicasgeneralesdelnogalelegidoPanel.Title = 'Caracteristicas generales del nogal elegido';
            app.Image9 = uiimage(app.CaracteristicasgeneralesdelnogalelegidoPanel);
            app.Image9.ImageSource = fullfile(pathToMLAPP, 'iconos', 'computer.png');
            app.AlturamLabel = uilabel(app.CaracteristicasgeneralesdelnogalelegidoPanel);
            app.AlturamLabel.Text = 'Altura (m)';
            app.ElegidoAltura = uieditfield(app.CaracteristicasgeneralesdelnogalelegidoPanel, 'numeric');
            app.Image_8 = uiimage(app.CaracteristicasgeneralesdelnogalelegidoPanel);
            app.Image_8.ImageSource = fullfile(pathToMLAPP, 'iconos', 'tree copy 2.png');
            app.CantidadderamasLabel = uilabel(app.CaracteristicasgeneralesdelnogalelegidoPanel);
            app.CantidadderamasLabel.Text = 'Cantidad de ramas';
            app.ElegidoCantidadramas = uieditfield(app.CaracteristicasgeneralesdelnogalelegidoPanel, 'numeric');
            app.Image_10 = uiimage(app.CaracteristicasgeneralesdelnogalelegidoPanel);
            app.Image_10.ImageSource = fullfile(pathToMLAPP, 'iconos', 'branch.png');
            app.MximoordenLabel = uilabel(app.CaracteristicasgeneralesdelnogalelegidoPanel);
            app.MximoordenLabel.Text = 'Máximo orden';
            app.ElegidoMaxorden = uieditfield(app.CaracteristicasgeneralesdelnogalelegidoPanel, 'numeric');
            app.Image_11 = uiimage(app.CaracteristicasgeneralesdelnogalelegidoPanel);
            app.Image_11.ImageSource = fullfile(pathToMLAPP, 'iconos', 'branch.png');
            app.LargotroncomLabel = uilabel(app.CaracteristicasgeneralesdelnogalelegidoPanel);
            app.LargotroncomLabel.Text = 'Largo tronco (m)';
            app.ElegidoLargotronco = uieditfield(app.CaracteristicasgeneralesdelnogalelegidoPanel, 'numeric');
            app.Image_13 = uiimage(app.CaracteristicasgeneralesdelnogalelegidoPanel);
            app.Image_13.ImageSource = fullfile(pathToMLAPP, 'iconos', 'firewood.png');
            app.LargodelacopamLabel = uilabel(app.CaracteristicasgeneralesdelnogalelegidoPanel);
            app.LargodelacopamLabel.Text = 'Largo de la copa (m)';
            app.ElegidoLargocopa = uieditfield(app.CaracteristicasgeneralesdelnogalelegidoPanel, 'numeric');
            app.Image_14 = uiimage(app.CaracteristicasgeneralesdelnogalelegidoPanel);
            app.Image_14.ImageSource = fullfile(pathToMLAPP, 'iconos', 'crown.png');
            app.VolumendelacopaLabel = uilabel(app.CaracteristicasgeneralesdelnogalelegidoPanel);
            app.VolumendelacopaLabel.Text = 'Volumen de la copa';
            app.ElegidoVolumencopa = uieditfield(app.CaracteristicasgeneralesdelnogalelegidoPanel, 'numeric');
            app.DiametrodelacopamLabel = uilabel(app.CaracteristicasgeneralesdelnogalelegidoPanel);
            app.DiametrodelacopamLabel.Text = 'Diametro de la copa (m)';
            app.ElegidoDiametrocopa = uieditfield(app.CaracteristicasgeneralesdelnogalelegidoPanel, 'numeric');
            app.Image_18 = uiimage(app.CaracteristicasgeneralesdelnogalelegidoPanel);
            app.Image_18.ImageSource = fullfile(pathToMLAPP, 'iconos', 'crown.png');
            app.Image_19 = uiimage(app.CaracteristicasgeneralesdelnogalelegidoPanel);
            app.Image_19.ImageSource = fullfile(pathToMLAPP, 'iconos', 'crown.png');
            app.Finas = uieditfield(app.UIFigure, 'numeric');
            app.RamasfinasLabel = uilabel(app.UIFigure);
            app.RamasfinasLabel.Text = 'Ramas finas';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'iconos', 'branch copy.png');
            app.Luz = uieditfield(app.UIFigure, 'numeric');
            app.LAILabel = uilabel(app.UIFigure);
            app.LAILabel.Text = 'LAI';
            app.Image_6 = uiimage(app.UIFigure);
            app.Image_6.ImageSource = fullfile(pathToMLAPP, 'iconos', 'sun.png');
            app.CerrarButton = uibutton(app.UIFigure, 'push');
            app.CerrarButton.ButtonPushedFcn = createCallbackFcn(app, @CerrarButtonPushed, true);
            app.CerrarButton.Text = 'Cerrar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = modalFinal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end