classdef modalPoda < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DescartarestapodaButton       matlab.ui.control.Button
        AplicarestapodaButton         matlab.ui.control.Button
        Image2_2                      matlab.ui.control.Image
        IndicadoresdelaestructuradepodaPanel  matlab.ui.container.Panel
        Image_11                      matlab.ui.control.Image
        Image_10                      matlab.ui.control.Image
        Image_8                       matlab.ui.control.Image
        Image_7                       matlab.ui.control.Image
        Image_6                       matlab.ui.control.Image
        Image_5                       matlab.ui.control.Image
        CalidaddeluzLabel             matlab.ui.control.Label
        Luz                           matlab.ui.control.NumericEditField
        VolumendemaderapodadoLabel    matlab.ui.control.Label
        Volpodado                     matlab.ui.control.NumericEditField
        AperturadecopaLabel           matlab.ui.control.Label
        Angulos                       matlab.ui.control.NumericEditField
        DistribucinhelicoidalLabel_2  matlab.ui.control.Label
        Dishelicoidal                 matlab.ui.control.NumericEditField
        RamasfinasLabel_2             matlab.ui.control.Label
        Finas                         matlab.ui.control.NumericEditField
        DistribucinverticalLabel      matlab.ui.control.Label
        Disvertical                   matlab.ui.control.NumericEditField
        Image                         matlab.ui.control.Image
        RamasprimariasLabel           matlab.ui.control.Label
        Primarias                     matlab.ui.control.NumericEditField
        Propuestadepoda1ButtonGroup   matlab.ui.container.ButtonGroup
        Image2                        matlab.ui.control.Image
        AxesPoda                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        PrincipalApp % referencia a la aplicacion principal
        num_qsm % es el indice al que pertenece la estructura
    end
    
    methods (Access = public)
        
        function setPrincipalApp(app, principalApp, cylinder)
            app.PrincipalApp = principalApp;
            plot_cylinder_model(cylinder, 'order', app.AxesPoda, 10);
            app.AxesPoda.ButtonDownFcn = @(src, event) activarRotacion(app, app.AxesPoda);
        end
    end
    
    methods (Access = private)
        
        function activarRotacion(app, ax)
            h = rotate3d(app.UIFigure);
            h.Enable = 'on';
            axes(ax);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: DescartarestapodaButton
        function DescartarestapodaButtonPushed(app, event)
            delete(app.UIFigure);
        end

        % Button pushed function: AplicarestapodaButton
        function AplicarestapodaButtonPushed(app, event)

            % indicar el nogal que se selecciono
            app.PrincipalApp.seleccionado = app.num_qsm;
            seleccionado = app.num_qsm;
           
            % nogal seleccionado
            if seleccionado > 1
                nogal_elegido = app.PrincipalApp.Eliteants(seleccionado);
            else
                nogal_elegido = app.PrincipalApp.best(seleccionado);
            end

            % se crea el modal
            modalElegido = modalFinal();
            
            %aqui se grafica el modelo de cilindros
            modalElegido.setGrafica(nogal_elegido.cylinder);
            
            % aqui se envian los valores de los objetivos
            modalElegido.Primarias.Value = app.Primarias.Value;
            modalElegido.Finas.Value = app.Finas.Value;
            modalElegido.Dishelicoidal.Value = app.Dishelicoidal.Value;
            modalElegido.Volpodado.Value = app.Volpodado.Value;
            modalElegido.Disvertical.Value = app.Disvertical.Value;
            modalElegido.Angulos.Value = app.Angulos.Value;
            modalElegido.Luz.Value = app.Luz.Value;

            % aqui se envian las caracteristicas generales del nogal
            modalElegido.ElegidoAltura.Value = double(nogal_elegido.treeData.TreeHeight);
            modalElegido.ElegidoCantidadramas.Value = double(nogal_elegido.treeData.NumberBranches);
            modalElegido.ElegidoMaxorden.Value = double(nogal_elegido.treeData.MaxBranchOrder);
            modalElegido.ElegidoLargotronco.Value = double(nogal_elegido.treeData.TrunkLength);
            modalElegido.ElegidoLargocopa.Value = double(nogal_elegido.treeData.CrownLength);
            modalElegido.ElegidoVolumencopa.Value = double(nogal_elegido.treeData.CrownVolumeConv);
            modalElegido.ElegidoDiametrocopa.Value = double(nogal_elegido.treeData.CrownDiamMax);
            delete(app.UIFigure);

            % app.PrincipalApp.ElegidoAltura.Value = double(app.PrincipalApp.best(app.num_qsm).treedata.TreeHeight);
            % app.PrincipalApp.ElegidoVolumen.Value = double(app.PrincipalApp.best(app.num_qsm).treedata.TotalVolume);
            % app.PrincipalApp.ElegidoVolumentronco.Value = double(app.PrincipalApp.best(app.num_qsm).treedata.TrunkVolume);
            % app.PrincipalApp.ElegidoVolumenramas.Value = double(app.PrincipalApp.best(app.num_qsm).treedata.BranchVolume);
            % app.PrincipalApp.ElegidoCantidadramas.Value = double(app.PrincipalApp.best(app.num_qsm).treedata.NumberBranches);
            % app.PrincipalApp.ElegidoMaxorden.Value = double(app.PrincipalApp.best(app.num_qsm).treedata.MaxBranchOrder);
            % app.PrincipalApp.ElegidoArearamas.Value = double(app.PrincipalApp.best(app.num_qsm).treedata.BranchArea);
            % app.PrincipalApp.ElegidoLargotronco.Value = double(app.PrincipalApp.best(app.num_qsm).treedata.TrunkLength);
            % app.PrincipalApp.ElegidoLargocopa.Value = double(app.PrincipalApp.best(app.num_qsm).treedata.CrownLength);
            % app.PrincipalApp.ElegidoVolumencopa.Value = double(app.PrincipalApp.best(app.num_qsm).treedata.CrownVolumeConv);
            % app.PrincipalApp.ElegidoAreanogal.Value = double(app.PrincipalApp.best(app.num_qsm).treedata.TotalArea);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AxesPoda = uiaxes(app.UIFigure);
            app.AxesPoda.XTick = [0 1 2 3 4 5 6 7 8 9 10];
            app.AxesPoda.XTickLabel = '';
            app.AxesPoda.YTick = [0 1 2 3 4 5 6 7 8 9 10];
            app.AxesPoda.YTickLabel = '';
            app.AxesPoda.ZTick = [0 1 2 3 4 5 6 7 8 9 10];
            app.Propuestadepoda1ButtonGroup = uibuttongroup(app.UIFigure);
            app.Propuestadepoda1ButtonGroup.Title = 'Propuesta de poda 1';
            app.Image2 = uiimage(app.Propuestadepoda1ButtonGroup);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'iconos', 'tree.png');
            app.IndicadoresdelaestructuradepodaPanel = uipanel(app.UIFigure);
            app.IndicadoresdelaestructuradepodaPanel.Title = 'Indicadores de la estructura de poda';
            app.Primarias = uieditfield(app.IndicadoresdelaestructuradepodaPanel, 'numeric');
            app.RamasprimariasLabel = uilabel(app.IndicadoresdelaestructuradepodaPanel);
            app.RamasprimariasLabel.Text = 'Ramas primarias';
            app.Image = uiimage(app.IndicadoresdelaestructuradepodaPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'iconos', 'branch.png');
            app.Disvertical = uieditfield(app.IndicadoresdelaestructuradepodaPanel, 'numeric');
            app.DistribucinverticalLabel = uilabel(app.IndicadoresdelaestructuradepodaPanel);
            app.DistribucinverticalLabel.Text = 'Distribución vertical';
            app.Finas = uieditfield(app.IndicadoresdelaestructuradepodaPanel, 'numeric');
            app.RamasfinasLabel_2 = uilabel(app.IndicadoresdelaestructuradepodaPanel);
            app.RamasfinasLabel_2.Text = 'Ramas finas';
            app.Dishelicoidal = uieditfield(app.IndicadoresdelaestructuradepodaPanel, 'numeric');
            app.DistribucinhelicoidalLabel_2 = uilabel(app.IndicadoresdelaestructuradepodaPanel);
            app.DistribucinhelicoidalLabel_2.Text = 'Distribución helicoidal';
            app.Angulos = uieditfield(app.IndicadoresdelaestructuradepodaPanel, 'numeric');
            app.AperturadecopaLabel = uilabel(app.IndicadoresdelaestructuradepodaPanel);
            app.AperturadecopaLabel.Text = 'Apertura de copa';
            app.Volpodado = uieditfield(app.IndicadoresdelaestructuradepodaPanel, 'numeric');
            app.VolumendemaderapodadoLabel = uilabel(app.IndicadoresdelaestructuradepodaPanel);
            app.VolumendemaderapodadoLabel.Text = 'Volumen de madera podado';
            app.Luz = uieditfield(app.IndicadoresdelaestructuradepodaPanel, 'numeric');
            app.CalidaddeluzLabel = uilabel(app.IndicadoresdelaestructuradepodaPanel);
            app.CalidaddeluzLabel.Text = 'Calidad de luz';
            app.Image_5 = uiimage(app.IndicadoresdelaestructuradepodaPanel);
            app.Image_5.ImageSource = fullfile(pathToMLAPP, 'iconos', 'branch copy.png');
            app.Image_6 = uiimage(app.IndicadoresdelaestructuradepodaPanel);
            app.Image_6.ImageSource = fullfile(pathToMLAPP, 'iconos', 'vertical-farming.png');
            app.Image_7 = uiimage(app.IndicadoresdelaestructuradepodaPanel);
            app.Image_7.ImageSource = fullfile(pathToMLAPP, 'iconos', 'angle.png');
            app.Image_8 = uiimage(app.IndicadoresdelaestructuradepodaPanel);
            app.Image_8.ImageSource = fullfile(pathToMLAPP, 'iconos', '360-degrees.png');
            app.Image_10 = uiimage(app.IndicadoresdelaestructuradepodaPanel);
            app.Image_10.ImageSource = fullfile(pathToMLAPP, 'iconos', 'sun.png');
            app.Image_11 = uiimage(app.IndicadoresdelaestructuradepodaPanel);
            app.Image_11.ImageSource = fullfile(pathToMLAPP, 'iconos', 'pruning-shears.png');
            app.Image2_2 = uiimage(app.UIFigure);
            app.Image2_2.ImageSource = fullfile(pathToMLAPP, 'iconos', 'monitoring.png');
            app.AplicarestapodaButton = uibutton(app.UIFigure, 'push');
            app.AplicarestapodaButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarestapodaButtonPushed, true);
            app.AplicarestapodaButton.Text = 'Aplicar esta poda';
            app.DescartarestapodaButton = uibutton(app.UIFigure, 'push');
            app.DescartarestapodaButton.ButtonPushedFcn = createCallbackFcn(app, @DescartarestapodaButtonPushed, true);
            app.DescartarestapodaButton.Text = 'Descartar esta poda';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = modalPoda

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end